classdef GUIBiomecanica < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        menAyuda            matlab.ui.container.Menu
        tabgAnalisis        matlab.ui.container.TabGroup
        tabDatos            matlab.ui.container.Tab
        txtEAP              matlab.ui.control.EditField
        EAPEditFieldLabel   matlab.ui.control.Label
        txteMax             matlab.ui.control.EditField
        emaxLabel           matlab.ui.control.Label
        txteMin             matlab.ui.control.EditField
        eminLabel           matlab.ui.control.Label
        tblDatos            matlab.ui.control.Table
        tabGrafica          matlab.ui.container.Tab
        drpArticulaciones   matlab.ui.control.DropDown
        axsAnalisis         matlab.ui.control.UIAxes
        tabForward          matlab.ui.container.Tab
        lblErrorD           matlab.ui.control.Label
        lblErrorI           matlab.ui.control.Label
        lblError            matlab.ui.control.Label
        lblAproxD           matlab.ui.control.Label
        lblPuntoD           matlab.ui.control.Label
        lblAproxI           matlab.ui.control.Label
        lblPuntoI           matlab.ui.control.Label
        lblAproximado       matlab.ui.control.Label
        lblReal             matlab.ui.control.Label
        lblPieD             matlab.ui.control.Label
        lblPieI             matlab.ui.control.Label
        tblLinks            matlab.ui.control.Table
        tblAngulos          matlab.ui.control.Table
        Panel               matlab.ui.container.Panel
        lblMapaColor        matlab.ui.control.Label
        knbSup              matlab.ui.control.Knob
        knbInf              matlab.ui.control.Knob
        txtaMensajes        matlab.ui.control.TextArea
        Lamp                matlab.ui.control.Lamp
        IniciarSwitch       matlab.ui.control.Switch
        IniciarSwitchLabel  matlab.ui.control.Label
        axsPuntos           matlab.ui.control.UIAxes
        axsPosicion         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Valor1 = 0;
        Valor2 = 4096;
        colors = ['r';'g';'b';'c';'y';'m'];
        SkeletonConnectionMap = [ 
                          [4 3];  % Cuello
                          [3 21]; % Cabeza
                          [21 2]; % Pierna derecha
                          [2 1];
                          [21 9];
                          [9 10];  % Cadera
                          [10 11];
                          [11 12]; % Pierna Izquierda
                          [12 24];
                          [12 25];
                          [21 5];  % Columna vertebral
                          [5 6];
                          [6 7];   % Mano izquierda
                          [7 8];
                          [8 22];
                          [8 23];
                          [1 17];
                          [17 18];
                          [18 19];  % Mano derecha
                          [19 20];
                          [1 13];
                          [13 14];
                          [14 15];
                          [15 16];
                        ];
    end

    methods (Access = private)
        
        function graficarPuntos(app,punto1,punto2,titulo)
            app.axsAnalisis.Title.String = titulo;
            hold(app.axsAnalisis,'off');
            plot(app.axsAnalisis,1,punto1,'.','MarkerSize',20,'DisplayName','Izquierda');
            hold(app.axsAnalisis,'on');
            plot(app.axsAnalisis,2,punto2,'.','MarkerSize',20,'DisplayName','Derecha');
            legend(app.axsAnalisis);
            
        end
        
        %Calcula el angulo entre dos vectores
        function theta = calcularAngulo(~,v1,v2)
            theta = round(acosd(dot(v1,v2)/(norm(v1)*norm(v2))));
        end
        
        %grafica los puntos de dos articulaciones
        function graficarArticulaciones(app,value,datos)
            try
                switch value
                    %se grafica solo el punto Y para observar la altura de
                    %cada ariticulación
                    case 'Cadera' 
                          graficarPuntos(app,datos(1,2),datos(5,2),'Cadera');
                    case 'Rodilla'
                        graficarPuntos(app,datos(2,2),datos(6,2),'Rodilla');
                    case 'Tobillo'
                        graficarPuntos(app,datos(3,2),datos(7,2),'Tobillo');
                    case 'Pie'
                        graficarPuntos(app,datos(4,2),datos(8,2),'Pie');
                end
                
    
            catch
                plot(app.axsAnalisis,0,0);
            end
        end
    
        %grafica el esqueleto generado por los puntos adquiridos del Kinect
        function graficarEsqueleto(app,nBodies,skeletonJoints)
            for i = 1:24
                for body = 1:nBodies                                    
                    X1 = [skeletonJoints(app.SkeletonConnectionMap(i,1),1,body); skeletonJoints(app.SkeletonConnectionMap(i,2),1,body)];
                    Y1 = [skeletonJoints(app.SkeletonConnectionMap(i,1),2,body), skeletonJoints(app.SkeletonConnectionMap(i,2),2,body)];                                    
                    plot(app.axsPuntos,X1,Y1, 'LineWidth', 2, 'LineStyle', '-' , 'Marker', '+', 'Color', app.colors(body), 'MarkerEdgeColor','b');                                                                                                                                                                                                                                                         
                end  
                hold(app.axsPuntos,'on');                                
            end
            hold(app.axsPuntos,'off');
        end
        
        %calcula la posición x,y para el marco de referencia del pie con
        %respecto al de la cadera
        function [x,y] = posicion(app,angulos,links)
            x = links(1)*sin(angulos(1))+links(2)*sin(angulos(1)+angulos(2))+links(3)*cos(angulos(1)+angulos(2)+angulos(3));
            y = -links(1)*cos(angulos(1))-links(2)*cos(angulos(1)+angulos(2))+links(3)*sin(angulos(1)+angulos(2)+angulos(3));
        end
        
        %calcula el error porcentual entre dos puntos
        function [Ep] = errorP(app,real,aprox)
            Ep = round((abs(aprox-real))/abs(real)*100,2);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clearAllMemoizedCaches;
            app.knbInf.Value = app.Valor1;
            app.knbSup.Value = app.Valor2;
            app.Lamp.Color = [0.64,0.08,0.18];
            app.tblDatos.Data = zeros([3 1]);   
            app.tblAngulos.Data = zeros([3 2]);
            app.tblLinks.Data = zeros([3 2]);
            app.axsPosicion.YLim = [0 380];
            app.axsPosicion.XLim = [0 512]; %514 368
            app.axsPuntos.YLim = [0 380];
            app.axsPuntos.XLim = [0 512];
            app.txtaMensajes.Value = "Iniciar Programa";
            monitor = get(0,'ScreenSize');
            interfaz = app.UIFigure.Position;
            x = (monitor(3)/2)-(interfaz(3)/2);
            y = (monitor(4)/2)-(interfaz(4)/2);
            app.UIFigure.Position = [x y interfaz(3) interfaz(4)];
        end

        % Value changed function: IniciarSwitch
        function IniciarSwitchValueChanged(app, event)
            global depthVid depthMetaData e
            value = app.IniciarSwitch.Value;
            if strcmp(value,'On')
                try
                    close all;
                    clc;
                    app.Lamp.Color = [0.00,1.00,0.00];
                    inicial = zeros([1 3]);                    
                                    
                    app.txtaMensajes.Value = "Reseteando imagen";
                    pause(3);
                    imaqreset; %Resetea el objeto de imagenes
                
                    app.txtaMensajes.Value = "Creando objeto de video";
                    pause(3);
                    depthVid = videoinput('kinect', 2);
                    %colorVid = videoinput('kinect', 1);                    
                
                    app.txtaMensajes.Value = "Configurando Frames";
                    pause(3);
                    %confuguracion del disparador                   
                    depthVid.Timeout = 200; %se asigna un tiempo de espera para toma de frames
                    triggerconfig(depthVid,'manual');
                    depthVid.FramesPerTrigger = 1;
                    depthVid.TriggerRepeat = inf;
                    set(getselectedsource(depthVid),'EnableBodyTracking','on');
                    
                    app.txtaMensajes.Value = "Iniciando Video";
                    pause(3);
                    %Inicio del video de profundidad
                    start(depthVid)
                    himg = figure; %crea un objeto figure   
                    %ejes = app.axsPosicion;                 
                    
             
                    app.txtaMensajes.Value = "Adquisición de Datos iniciada";
                    %ciclo para vizualizar los puntos en la figura
                    while ishandle(himg)
                        himg.Visible = "off";
                        trigger(depthVid);
                        [depthMap,~,depthMetaData] = getdata(depthVid);                        
                        %anyBodiesTracked = any(depthMetaData.IsBodyTracked ~= 0);
                        trackedBodies = find(depthMetaData.IsBodyTracked);
                        nBodies = length(trackedBodies);                        
                        imshow(depthMap,[app.Valor1 app.Valor2],'Parent',app.axsPosicion);                        
                        frames = depthMetaData.FrameNumber;                        
                        
                        %verifica si la suma del seguimiento del cuerpo es mayor a cero
                        if sum(depthMetaData.IsBodyTracked)>0
                            skeletonJoints = depthMetaData.DepthJointIndices(:,:,depthMetaData.IsBodyTracked);
                            datos = depthMetaData.JointPositions(:,:,depthMetaData.IsBodyTracked);
                            plot(app.axsPosicion,skeletonJoints(:,1), skeletonJoints(:,2),'*r');

                            graficarEsqueleto(app,nBodies,skeletonJoints);                                                       
                                                        
                            % Los calculos son con Derecha

                            %Calculo norma de e                            
                            e(frames) = norm(minus(datos(14,:),datos(15,:)));
                            
                            
                            %Efecto de acortamiento de la pierna
                            app.txteMin.Value = num2str(min(e));
                            app.txteMax.Value = num2str(max(e));
                            EAP = (max(e)-min(e))/max(e)*100;
                            app.txtEAP.Value = num2str(abs(EAP));
                           
                            
                            %Longitud de paso
                            startT = datetime('now');
                            LP = abs(datos(15,2) - datos(18,2));
                            endT = datetime('now');
                            time = seconds(endT-startT);
                            
                            ref = 2.8;
                            if round(inicial,1) < ref
                                %Longitud de zancada
                                final = datos(15,:);
                                LZ = norm(final-inicial);
                                inicial = final;
                            else
                                LZ = 0;
                                inicial = inicial;
                            end                                                                                       
                                                        
                            %Velocidad de marcha
                            VM = round(LP/time);

                            %datos = [skeletonJoints(13:20,1) skeletonJoints(13:20,2)];                          
                            %app.tblDatos.Data = datos(13:20,:);
                            app.tblDatos.Data = [LP;LZ;VM];

                                                       
                            %Calculo de angulos
                            ECRI = calcularAngulo(app,minus(skeletonJoints(1,:),skeletonJoints(13,:)),minus(skeletonJoints(14,:),skeletonJoints(13,:)));
                            ECRD = calcularAngulo(app,minus(skeletonJoints(1,:),skeletonJoints(17,:)),minus(skeletonJoints(18,:),skeletonJoints(17,:))); 
                            CRTI = calcularAngulo(app,minus(skeletonJoints(13,:),skeletonJoints(14,:)),minus(skeletonJoints(15,:),skeletonJoints(14,:)));                            
                            CRTD = calcularAngulo(app,minus(skeletonJoints(17,:),skeletonJoints(18,:)),minus(skeletonJoints(19,:),skeletonJoints(18,:)));  
                            RTPI = calcularAngulo(app,minus(skeletonJoints(14,:),skeletonJoints(15,:)),minus(skeletonJoints(16,:),skeletonJoints(15,:)));
                            RTPD = calcularAngulo(app,minus(skeletonJoints(18,:),skeletonJoints(19,:)),minus(skeletonJoints(20,:),skeletonJoints(19,:)));

                            app.tblAngulos.Data = [ECRI ECRD; CRTI CRTD; RTPI RTPD];

                            CRI = norm(minus(skeletonJoints(13,:),skeletonJoints(14,:)));
                            CRD = norm(minus(skeletonJoints(17,:),skeletonJoints(18,:)));

                            RTI = norm(minus(skeletonJoints(14,:),skeletonJoints(15,:)));
                            RTD = norm(minus(skeletonJoints(18,:),skeletonJoints(19,:)));

                            TPI = norm(minus(skeletonJoints(15,:),skeletonJoints(16,:)));
                            TPD = norm(minus(skeletonJoints(19,:),skeletonJoints(20,:)));

                            app.tblLinks.Data = [CRI CRD; RTI RTD; TPI TPD];

                            app.lblPuntoI.Text = "("+num2str(round(skeletonJoints(16,1),2))+","+num2str(round(skeletonJoints(16,2),2))+")";
                            app.lblPuntoD.Text = "("+num2str(round(skeletonJoints(20,1),2))+","+num2str(round(skeletonJoints(20,2),2))+")";

                            [x1,y1] = posicion(app,[ECRI,CRTI,RTPI],[CRI,RTI,TPI]);
                            [x2,y2] = posicion(app,[ECRD,CRTD,RTPD],[CRD,RTD,TPD]);

                            aproxI = [round(skeletonJoints(13,1)+abs(y1),2) round(skeletonJoints(13,2)+abs(x1),2)];
                            aproxD = [round(skeletonJoints(17,1)+abs(y2),2) round(skeletonJoints(17,2)+abs(x2),2)];

                            app.lblAproxI.Text = "("+num2str(aproxI(1))+","+num2str(aproxI(2))+")";
                            app.lblAproxD.Text = "("+num2str(aproxD(1))+","+num2str(aproxD(2))+")";

                            EpI1 = errorP(app,skeletonJoints(16,1),aproxI(1));
                            EpI2 = errorP(app,skeletonJoints(16,2),aproxI(2));
                            EpD1 = errorP(app,skeletonJoints(20,1),aproxD(1));
                            EpD2 = errorP(app,skeletonJoints(20,2),aproxD(2));

                            app.lblErrorI.Text = "("+num2str(round(EpI1))+"%,"+num2str(round(EpI2))+"%)";
                            app.lblErrorD.Text = "("+num2str(round(EpD1))+"%,"+num2str(round(EpD2))+"%)";

                            
%                             Cadera rodilla tobillo
% 
%                             13 14 15
%                             
%                             17 18 19
%                             
%                             rodilla tobillo pie
%                             
%                             14 15 16
%                             
%                             18 19 20

%                             13 1
%                             14 2
%                             15 3
%                             16 4
%                             17 5
%                             18 6
%                             19 7
%                             20 8

                            value = app.drpArticulaciones.Value;
            
                            graficarArticulaciones(app,value,datos);
                            
                        else
                            imshow(depthMap,[app.Valor1 app.Valor2],'Parent',app.axsPosicion);
                            cla(app.axsPuntos);
                            %imaqmontage(depthMap, 'Parent', app.axsPosicion);
                        end%if-else
                        
                    end%while
                
                catch ME
                    
                    switch ME.identifier
                        case 'imaq:kinect:createDepthInstance'
                            app.txtaMensajes.Value = 'No es posible crear un objeto de video, por favor apage el programa';
                            app.Lamp.Color = [1.00,1.00,0.00];
                        case 'imaq:videoinput:noDevices'
                            app.txtaMensajes.Value = 'El dispositivo Kinect no se encuentra conectado.';
                            app.Lamp.Color = [1.00,1.00,0.00];
                        case 'MATLAB:nonExistentField'
                            app.txtaMensajes.Value = 'El seguimiento del cuerpo, no se puede realizar.';
                            app.Lamp.Color = [1.00,1.00,0.00];
                        case 'imaq:getdata:notRunning'
                            app.txtaMensajes.Value = 'No se puede mostrar video o no hay frames disponibles.';
                            app.Lamp.Color = [1.00,1.00,0.00];
                        case 'imaq:getdata:invalidOBJ'
                            app.txtaMensajes.Value = 'Se ha eliminado el video, programa finalizado.';
                        case 'imaq:getdata:timeout'
                            app.txtaMensajes.Value = 'El tiempo de espera esta agotado.';                        
                        otherwise
                            app.txtaMensajes.Value = 'Hay un error de ejucuación, reiniciar el programa';
                            app.Lamp.Color = [1.00,1.00,0.00];
                            disp(ME)
                    end%switch
                    
                end%try-catch
                
                
            else
                app.Lamp.Color = [0.64,0.08,0.18];
                app.txtaMensajes.Value = "Programa Finalizado";
                if depthVid ~= 0
                    stop(depthVid);                    
                    flushdata(depthVid);
                    delete(depthVid);
                else
                    clear;
                end                
                close all;                
            end%end if-else
        end

        % Menu selected function: menAyuda
        function menAyudaMenuSelected(app, event)
            
        end

        % Value changing function: knbInf
        function knbInfValueChanging(app, event)
            changingValue = event.Value;
            app.Valor1 = changingValue;
        end

        % Value changing function: knbSup
        function knbSupValueChanging(app, event)
            changingValue = event.Value;
            app.Valor2 = changingValue;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.menAyuda = uimenu(app.UIFigure);
            app.menAyuda.MenuSelectedFcn = createCallbackFcn(app, @menAyudaMenuSelected, true);
            app.menAyuda.Text = 'Ayuda';
            app.axsPosicion = uiaxes(app.UIFigure);
            title(app.axsPosicion, 'Video')
            app.axsPosicion.XTick = [];
            app.axsPosicion.XTickLabel = '';
            app.axsPosicion.YTick = [];
            app.axsPosicion.LineStyleOrder = {'-'};
            app.axsPuntos = uiaxes(app.UIFigure);
            title(app.axsPuntos, 'Esqueleto')
            app.axsPuntos.YDir = 'reverse';
            app.Panel = uipanel(app.UIFigure);
            app.IniciarSwitchLabel = uilabel(app.Panel);
            app.IniciarSwitchLabel.Text = 'Iniciar';
            app.IniciarSwitch = uiswitch(app.Panel, 'slider');
            app.IniciarSwitch.ValueChangedFcn = createCallbackFcn(app, @IniciarSwitchValueChanged, true);
            app.Lamp = uilamp(app.Panel);
            app.txtaMensajes = uitextarea(app.Panel);
            app.txtaMensajes.Value = {''; ''};
            app.knbInf = uiknob(app.Panel, 'continuous');
            app.knbInf.Limits = [0 4096];
            app.knbInf.ValueChangingFcn = createCallbackFcn(app, @knbInfValueChanging, true);
            app.knbSup = uiknob(app.Panel, 'continuous');
            app.knbSup.Limits = [0 4096];
            app.knbSup.ValueChangingFcn = createCallbackFcn(app, @knbSupValueChanging, true);
            app.lblMapaColor = uilabel(app.Panel);
            app.lblMapaColor.Text = 'Mapa de Color [Valor1 Valor2]';
            app.tabgAnalisis = uitabgroup(app.UIFigure);
            app.tabDatos = uitab(app.tabgAnalisis);
            app.tabDatos.Title = 'Datos';
            app.tblDatos = uitable(app.tabDatos);
            app.tblDatos.ColumnName = '';
            app.tblDatos.RowName = {'Longitud de paso'; 'Longitud de zancada'; 'Velocidad de marcha'};
            app.eminLabel = uilabel(app.tabDatos);
            app.eminLabel.Text = 'e(min)';
            app.txteMin = uieditfield(app.tabDatos, 'text');
            app.txteMin.Value = '0';
            app.emaxLabel = uilabel(app.tabDatos);
            app.emaxLabel.Text = 'e(max)';
            app.txteMax = uieditfield(app.tabDatos, 'text');
            app.txteMax.Value = '0';
            app.EAPEditFieldLabel = uilabel(app.tabDatos);
            app.EAPEditFieldLabel.Text = 'EAP';
            app.txtEAP = uieditfield(app.tabDatos, 'text');
            app.txtEAP.Value = '0';
            app.tabGrafica = uitab(app.tabgAnalisis);
            app.tabGrafica.Title = 'Grafica';
            app.axsAnalisis = uiaxes(app.tabGrafica);
            ylabel(app.axsAnalisis, ' ')
            app.axsAnalisis.ZDir = 'reverse';
            app.drpArticulaciones = uidropdown(app.tabGrafica);
            app.drpArticulaciones.Items = {'Cadera', 'Rodilla', 'Tobillo', 'Pie'};
            app.drpArticulaciones.Value = 'Cadera';
            app.tabForward = uitab(app.tabgAnalisis);
            app.tabForward.Title = 'Real vs Aproximación';
            app.tblAngulos = uitable(app.tabForward);
            app.tblAngulos.ColumnName = {'Izquierdo'; 'Derecho'};
            app.tblAngulos.RowName = {'ECR'; 'CRT'; 'RTP'};
            app.tblLinks = uitable(app.tabForward);
            app.tblLinks.ColumnName = {'Izquierdo'; 'Derecho'};
            app.tblLinks.RowName = {'CR'; 'RT'; 'TP'};
            app.lblPieI = uilabel(app.tabForward);
            app.lblPieI.Text = 'Pie Izquierdo';
            app.lblPieD = uilabel(app.tabForward);
            app.lblPieD.Text = 'Pie Derecho';
            app.lblReal = uilabel(app.tabForward);
            app.lblReal.Text = 'Real';
            app.lblAproximado = uilabel(app.tabForward);
            app.lblAproximado.Text = 'Aproximado';
            app.lblPuntoI = uilabel(app.tabForward);
            app.lblPuntoI.Text = '(0,0)';
            app.lblAproxI = uilabel(app.tabForward);
            app.lblAproxI.Text = '(0,0)';
            app.lblPuntoD = uilabel(app.tabForward);
            app.lblPuntoD.Text = '(0,0)';
            app.lblAproxD = uilabel(app.tabForward);
            app.lblAproxD.Text = '(0,0)';
            app.lblError = uilabel(app.tabForward);
            app.lblError.Text = 'Error %';
            app.lblErrorI = uilabel(app.tabForward);
            app.lblErrorI.Text = '(0,0)';
            app.lblErrorD = uilabel(app.tabForward);
            app.lblErrorD.Text = '(0,0)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIBiomecanica

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end